classdef Efficiency_Calculator_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ShippingEfficiencyCalculatorLabel  matlab.ui.control.Label
        ResultsTextArea             matlab.ui.control.TextArea
        ResultsTextAreaLabel        matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        InputsTab                   matlab.ui.container.Tab
        UnitsInput                  matlab.ui.control.Switch
        MassInput                   matlab.ui.control.NumericEditField
        MasskgEditFieldLabel        matlab.ui.control.Label
        HeightInput                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel        matlab.ui.control.Label
        WidthInput                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel         matlab.ui.control.Label
        LengthInput                 matlab.ui.control.NumericEditField
        LengthEditFieldLabel        matlab.ui.control.Label
        TrailerInput                matlab.ui.control.DropDown
        TrailerDropDownLabel        matlab.ui.control.Label
        VertLabel                   matlab.ui.control.Label
        vertLbox                    matlab.ui.control.CheckBox
        vertWbox                    matlab.ui.control.CheckBox
        vertHbox                    matlab.ui.control.CheckBox
        OutputSettingsTab           matlab.ui.container.Tab
        UnitsSwitch                 matlab.ui.control.Switch
        OriginalDimensionsCheckBox  matlab.ui.control.CheckBox
        IdealDimensionsCheckBox     matlab.ui.control.CheckBox
        AdvancedSettingsTab         matlab.ui.container.Tab
        MassInput_4                 matlab.ui.control.NumericEditField
        PackMkgLabel_2              matlab.ui.control.Label
        trailerHeight               matlab.ui.control.NumericEditField
        WidthinLabel_2              matlab.ui.control.Label
        trailerMass                 matlab.ui.control.NumericEditField
        MasskgEditFieldLabel_2      matlab.ui.control.Label
        trailerWidth                matlab.ui.control.NumericEditField
        WidthinLabel                matlab.ui.control.Label
        trailerLength               matlab.ui.control.NumericEditField
        LengthinLabel               matlab.ui.control.Label
        LengthEditFieldLabel_6      matlab.ui.control.Label
        LengthEditFieldLabel_5      matlab.ui.control.Label
        HeightInput_2               matlab.ui.control.NumericEditField
        PackHinLabel                matlab.ui.control.Label
        MassInput_2                 matlab.ui.control.NumericEditField
        PackMkgLabel                matlab.ui.control.Label
        WidthInput_2                matlab.ui.control.NumericEditField
        PackWinLabel                matlab.ui.control.Label
        LengthInput_2               matlab.ui.control.NumericEditField
        PackagingLLabel             matlab.ui.control.Label
        HomePlateCheckBox           matlab.ui.control.CheckBox
        CalculateButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        ogL % inputted length
        ogW % inputted width
        ogH % inputted height
        ogM % inputted mass
        vDim = [102, 636, 102, 21772.434] % Trailer dimensions
        vertL % Can length be vertical
        vertW % Can width be vertical
        vertH % Can height be vertical
        unitsIn = "in." % mm or in as input values
        idealOut % will ideal dimensions be shown
        ogOut % will original dimensions be shown
        unitOut % mm or in as output values
        HomePlate % bool, is Home Plate being analyzed?
        vName % Trailer Van name
        costs % Dollar per mile per m^2 of HelioStat
        Out % string, output parameters
        pack % packaging units
        CostperMile % Description
    end
    
    methods (Access = private)
        
        function des_mat= full_fact(app, varargin)
            numarg = length(varargin);
            for i=1:numarg
                levels(i)=length(varargin{i});
                % Converting row vector to column vector
                if size(varargin{i},1)==1,varargin{i}=varargin{i}';end
            end
            % Check number of variables and levels of each variable
            if numarg<2
                error 'Minimum 2 variables are required'
            end
            if ~all(levels >1)
                error 'Each variables should have minimum 2 levels'
            end
            % Total number of design points
            total=prod(levels);
            %Initilization of output matrix
            des_mat=zeros(0,1);
            % Loop for full factorial points
            for i=1:numarg
                if i~=1 && i~=numarg
                    temp=zeros(0,1);
                    for j=1:prod(levels(1:i-1))
                        temp1=repmat(varargin{i},prod(levels(i+1:end)),1);
                        temp1=sortrows(temp1);
                        temp=[temp; temp1];
                    end
                elseif i==numarg
                    temp=repmat(varargin{i},total/levels(i),1);
                else
                    temp=repmat(varargin{i},total/levels(i),1);
                    temp=sortrows(temp);
                end
                des_mat=[des_mat temp];
            end

        end

        function HScombos = find_combos(app)    
            lowBnd = 0.8; % -20%
            uppBnd = 1.2; % +20%
            spc = 0.05; % [in] ~= 0.00254m
            
            HSin.L = app.ogL*lowBnd: app.ogL*spc: app.ogL*uppBnd; % L [in]
            HSin.W = app.ogW*lowBnd: app.ogW*spc: app.ogW*uppBnd; % W [in]
            HSin.H = app.ogH*lowBnd: app.ogH*spc: app.ogH*uppBnd; % H [in]
            HScombos = full_fact(app, HSin.L, HSin.W, HSin.H); % [in]
        end
        
        function res = calc_most_units(app, HSDims)
            Wblocks = 5; % when thin-side is along the width (53' direction), you need extra spacers for every ~26 units
            uniL = 70; % approximate width of shipping unit [in]
        
            van_w = app.vDim(1); % [in]
            van_l = app.vDim(2); % [in]
            van_H = app.vDim(3); % [in]
            van_M = app.vDim(4); % [lbs]
            max_vol = van_w * van_l * van_H; % [ft^3]
            
            blocks1.w = floor((van_w - app.pack(1) - app.pack(4)) ./ (HSDims(:, 1) + app.pack(1)));
            blocks1.l = floor(van_l ./ (HSDims(:, 2) + app.pack(2)));
            blocks1.H = floor(van_H ./ (HSDims(:, 3) + app.pack(3)));
        
            blocks2.w = blocks1.w;
            blocks2.l = floor(van_l ./ (HSDims(:, 3) + app.pack(2)));
            blocks2.H = floor(van_H ./ (HSDims(:, 2) + app.pack(3)));
        
            blocks3.w = floor(van_w ./ (HSDims(:, 3) + app.pack(4)));
            blocks3.l = floor((van_l - (Wblocks * (app.pack(1) + app.pack(4)))) ./ (HSDims(:, 1) + app.pack(1)));
            blocks3.H = blocks2.H;
        
            blocks4.w = floor(van_w ./ (HSDims(:, 2) + app.pack(4)));
            blocks4.l = blocks3.l;
            blocks4.H = blocks1.H;
        
            Units1 = blocks1.w .* blocks1.l .* blocks1.H;
            Units2 = blocks2.w .* blocks2.l .* blocks2.H;
            Units3 = blocks3.w .* blocks3.l .* blocks3.H;
            Units4 = blocks4.w .* blocks4.l .* blocks4.H;

            maxes = [max(Units1), max(Units2), max(Units3), max(Units4)];
        
            if max(maxes) == maxes(1)
                res.units = Units1;
                used.w = (blocks1.w .* (HSDims(:, 1) + app.pack(1))) + app.pack(1) + app.pack(4);
                used.l = blocks1.l .* (HSDims(:, 2) + app.pack(2));
                used.H = blocks1.H .* (HSDims(:, 3) + app.pack(3));
                res.HplusP = [(HSDims(:, 1) + app.pack(1)), (HSDims(:, 2) + app.pack(2)), (HSDims(:, 3) + app.pack(3))];
                res.ori_HSDims = [HSDims(:, 1), HSDims(:, 2), HSDims(:, 3)];
                res.weights = ((Units1 .* app.ogM) + ((blocks1.l .* blocks1.H) .* app.pack(5))) ./ van_M;
                res.blocks = [blocks1.w, blocks1.l, blocks1.H];
        
            elseif max(maxes) == maxes(2)
                res.units = Units2;
                used.w = (blocks2.w .* (HSDims(:, 1) + app.pack(1))) + app.pack(1) + app.pack(4);
                used.l = blocks2.l .* (HSDims(:, 3) + app.pack(2));
                used.H = blocks2.H .* (HSDims(:, 2) + app.pack(3));
                res.HplusP = [(HSDims(:, 1) + app.pack(1)), (HSDims(:, 3) + app.pack(2)), (HSDims(:, 2) + app.pack(3))];
                res.ori_HSDims = [HSDims(:, 1), HSDims(:, 3), HSDims(:, 2)];
                res.weights = ((Units2 .* app.ogM) + ((blocks2.l .* blocks2.H).*app.pack(5))) ./ van_M;
                res.blocks = [blocks2.w, blocks2.l, blocks2.H];
        
            elseif max(maxes) == maxes(3)
                res.units = Units3;
                used.w = blocks3.w .* (HSDims(:, 3) + app.pack(4));
                used.l = (blocks3.l .* (HSDims(:, 1) + app.pack(1))) + (Wblocks * (app.pack(1) + app.pack(4)));
                used.H = blocks3.H .* (HSDims(:, 2) + app.pack(3));
                res.HplusP = [(HSDims(:, 3) + app.pack(4)), (HSDims(:, 1) + app.pack(1)), (HSDims(:, 2) + app.pack(3))];
                res.ori_HSDims = [HSDims(:, 3), HSDims(:, 1), HSDims(:, 2)];
                res.weights = ((Units3 .* app.ogM) + (floor(van_l/uniL)*blocks3.H*app.pack(5))) ./ van_M;
                res.blocks = [blocks3.w, blocks3.l, blocks3.H];
        
            elseif max(maxes) == maxes(4)
                res.units = Units4;
                used.w = blocks4.w .* (HSDims(:, 2) + app.pack(4));
                used.l = (blocks4.l .* (HSDims(:, 1) + app.pack(1))) + (Wblocks * (app.pack(1) + app.pack(4)));
                used.H = blocks4.H .* (HSDims(:, 3) + app.pack(3));
                res.HplusP = [(HSDims(:, 2) + app.pack(4)), (HSDims(:, 1) + app.pack(1)), (HSDims(:, 3) + app.pack(3))];
                res.ori_HSDims = [HSDims(:, 2), HSDims(:, 1), HSDims(:, 3)];
                res.weights = ((Units4 .* app.ogM) + (floor(van_l/uniL)*blocks4.H*app.pack(5))) ./ van_M;
                res.blocks = [blocks4.w, blocks4.l, blocks4.H];
            end
        
            res.vol_eff = (used.w .* used.l .* used.H) ./ max_vol;
        end

        function DolPerM2 = adj_area(app, cost)
            % it will be more accurate to subtract certain L and W from the area    
            sin_ratio = 1.27/ 1.6421773608;
            in2m = 39.37; % inch -> [m]
        
            if app.HomePlate
                DolPerM2 = cost./((app.vDim(1)/in2m).*(app.vDim(2)/in2m)*sin_ratio);
            else, DolPerM2 = cost./((app.vDim(1)/in2m).*(app.vDim(2)/in2m));
            end
        end
        
        function ideal_dims = disp_ideal_data(app, totals)
            ind_limM = totals.weights < 1;
            inConv = 1;
            if app.unitsIn == "mm", inConv = 25.4; end % in -> [mm]

            ind = find(app.costs == min(app.costs(ind_limM)));
            temp_ideal_dims = totals.ori_HSDims(ind,:); % [in]
            ideal_dims = temp_ideal_dims * inConv;
            maxUnits = max(totals.units(ind_limM));
            l_dims = min(ideal_dims);
            h_dims = max(ideal_dims);
            best_vol_eff = totals.vol_eff(ind);
            low_vol_eff = min(best_vol_eff) * 100;
            high_vol_eff = max(best_vol_eff) * 100;
            line = sprintf('----------------------------------------------------------\n');
        
            if app.idealOut
                % Display various parameters based on ideal dimensions
                s1 = sprintf('Units Per truck:     %d \n', maxUnits);
                s2 = sprintf('Blocks [WxLxH]:   %d x %d x %d \n', totals.blocks(ind(1),1), totals.blocks(ind(1),2), totals.blocks(ind(1),3));
                
                if low_vol_eff == high_vol_eff, s3 = sprintf('Volume Eff. [%%]:    %f \n', low_vol_eff);
                else, s3 = sprintf('Volume Eff. [%%]:    %f - %f \n', low_vol_eff, high_vol_eff); end
                % sprintf('Max Vol. Eff. [%%]: %f \n', (max(totals.vol_eff)*100));
                % sprintf('HSplusP Max Dims [in]: %f, %f, %f \n', HSplusPdims(1), HSplusPdims(2), HSplusPdims(3));
                s4 = sprintf('Mass eff. [%%]:        %f \n\n', max(totals.weights(ind(1)))*100);
            
                if size(ideal_dims) == [1, 3]
                    l_dims = ideal_dims;
                    h_dims = ideal_dims;
                end
            
                if l_dims(1) == h_dims(1), s5 = sprintf('L [mm]:                  %f \n', l_dims(1)); 
                else, s5 = sprintf('L [mm]:                  %f - %f \n', l_dims(1), h_dims(1)); end
            
                if l_dims(2) == h_dims(2), s6 = sprintf('W [mm]:                 %f \n', l_dims(2)); 
                else, s6 = sprintf('W [mm]:                 %f - %f \n', l_dims(2), h_dims(2)); end
            
                if l_dims(3) == h_dims(3), s7 = sprintf('H [mm]:                 %f \n', l_dims(3)); 
                else, s7 = sprintf('H [mm]:                 %f - %f \n', l_dims(3), h_dims(3)); end
            
                app.Out = append(app.Out, s1, s2, s3, s4, s5, s6, s7);
                if app.ogOut, app.Out = append(app.Out, line); end
            end
        
            if app.ogOut
                % kg2lbs = 2.205;
                og_dims = [app.ogL, app.ogW, app.ogH];
                ogDta = calc_most_units(app, og_dims);
                og_cost = adj_area(app, (app.CostperMile / ogDta.units));
        
                s1 = sprintf('Using Orig. Dims \n');
                s2 = sprintf('Cost [$/mile/m^2]: %f \n', og_cost);
                s3 = sprintf('Units:                     %d \n', ogDta.units);
                s4 = sprintf('Blocks [LxWxH]:    %d x %d x %d \n', ogDta.blocks(1), ogDta.blocks(2), ogDta.blocks(3));
                s5 = sprintf('Volume Eff. [%%]:     %f \n', ogDta.vol_eff*100);
                s6 = sprintf('Mass Eff. [%%]:        %f \n\n', ogDta.weights*100);
                s7 = sprintf('Dim w/ pack. [in]: %f, %f, %f \n', ogDta.HplusP(1), ogDta.HplusP(2), ogDta.HplusP(3));
                app.Out = append(app.Out, s1, s2, s3, s4, s5, s6);
                % if app.HplusP, app.Out = append(app.Out, s7);
            end
        
        end

        function get_ideal_cost_dims(app, results)
            min_cost = min(app.costs);
            s1 = sprintf('Dims w/ Min. Cost \n');
            s2 = sprintf('Cost [$/mile/m^2]: %f \n', min_cost);
            if app.idealOut, app.Out = append(s1, s2); 
            else, app.Out = "";
            end
            disp_ideal_data(app, results);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: HeightInput, HomePlateCheckBox, 
        % ...and 4 other components
        function Inputs(app, event)
            app.ogL = app.LengthInput.Value;
            app.ogW = app.WidthInput.Value;
            app.ogH = app.HeightInput.Value;
            app.ogM = app.MassInput.Value;
            app.vertL = app.vertLbox.Value;
            app.vertW = app.vertWbox.Value;
            app.vertH = app.vertHbox.Value;
            app.HomePlate = app.HomePlateCheckBox.Value;
            app.unitsIn = app.UnitsInput.Value;
        end

        % Value changed function: TrailerInput
        function TrailerSelection(app, event)
            app.vName = app.TrailerInput.Value;
            % W[in], L[in], H[in], M[kg]
            vDims = [
                [102, 636, 102, 21772.434]; % Flatbed
                [98, 636, 108, 20411.657]; % Dry Van
                [102, 576, 98, 20411.657]; % Conestoga
                [92, 474, 106, 26308.358]; % HC Sea
                [92, 474, 94, 27215.543]; % Sea
                ];
            if app.vName == "53' Flatbed" , app.vDim = vDims(1,:);
            elseif app.vName == "53' Dry Van", app.vDim = vDims(2,:);
            elseif app.vName == "48' Conestoga", app.vDim = vDims(3,:);
            elseif app.vName == "40' HC Sea", app.vDim = vDims(4,:);
            elseif app.vName == "40' Sea", app.vDim = vDims(4,:);
            end

            app.trailerWidth.Value = app.vDim(1);
            app.trailerLength.Value = app.vDim(2);
            app.trailerHeight.Value = app.vDim(3);
            app.trailerMass.Value = app.vDim(4);
        end

        % Value changed function: IdealDimensionsCheckBox, 
        % ...and 2 other components
        function OutputSettings(app, event)
            app.idealOut = app.IdealDimensionsCheckBox.Value; % bool
            app.ogOut = app.OriginalDimensionsCheckBox.Value; % bool
            app.unitOut = app.UnitsSwitch.Value;
        end

        % Button pushed function: CalculateButton
        function Calculate(app, event)
            % Sp [in], W [in], H[in], L [in], M [kg];
            Inputs(app);
            OutputSettings(app);
            app.pack = [2, 6, 6.375, 7, 442.253]; 
            app.CostperMile = 3.24;
            
            HScombos = find_combos(app); % [in]
            results = calc_most_units(app, HScombos);

            DolPerMiPerHS = app.CostperMile ./ results.units; % [$]
            app.costs = adj_area(app, DolPerMiPerHS);
            get_ideal_cost_dims(app, results);
            Output(app);
        end

        % Value changed function: ResultsTextArea
        function Output(app, event)
            app.ResultsTextArea.Value = app.Out;
        end

        % Value changed function: UnitsInput
        function UnitsInDetect(app, event)
            in2mm = 25.4;
            if app.unitsIn == "in."
                app.LengthInput.Value = app.LengthInput.Value * in2mm;
                app.WidthInput.Value = app.WidthInput.Value * in2mm;
                app.HeightInput.Value = app.HeightInput.Value * in2mm;
            elseif app.unitsIn == "mm."
                app.LengthInput.Value = app.LengthInput.Value / in2mm;
                app.WidthInput.Value = app.WidthInput.Value / in2mm;
                app.HeightInput.Value = app.HeightInput.Value / in2mm;
            end
            app.unitsIn = app.UnitsInput.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @Calculate, true);
            app.CalculateButton.Text = 'Calculate';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.vertHbox = uicheckbox(app.InputsTab);
            app.vertHbox.Text = '';
            app.vertHbox.Value = true;
            app.vertWbox = uicheckbox(app.InputsTab);
            app.vertWbox.Text = '';
            app.vertWbox.Value = true;
            app.vertLbox = uicheckbox(app.InputsTab);
            app.vertLbox.ValueChangedFcn = createCallbackFcn(app, @Inputs, true);
            app.vertLbox.Text = '';
            app.vertLbox.Value = true;
            app.VertLabel = uilabel(app.InputsTab);
            app.VertLabel.Text = 'Vert';
            app.TrailerDropDownLabel = uilabel(app.InputsTab);
            app.TrailerDropDownLabel.Text = 'Trailer';
            app.TrailerInput = uidropdown(app.InputsTab);
            app.TrailerInput.Items = {'53'' Flatbed', '53'' Dry Van', '48'' Conestoga', '40'' HC Sea', '40'' Sea'};
            app.TrailerInput.ValueChangedFcn = createCallbackFcn(app, @TrailerSelection, true);
            app.TrailerInput.Value = '53'' Flatbed';
            app.LengthEditFieldLabel = uilabel(app.InputsTab);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthInput = uieditfield(app.InputsTab, 'numeric');
            app.LengthInput.ValueChangedFcn = createCallbackFcn(app, @Inputs, true);
            app.LengthInput.Value = 7.6;
            app.WidthEditFieldLabel = uilabel(app.InputsTab);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthInput = uieditfield(app.InputsTab, 'numeric');
            app.WidthInput.ValueChangedFcn = createCallbackFcn(app, @Inputs, true);
            app.WidthInput.Value = 47.4;
            app.HeightEditFieldLabel = uilabel(app.InputsTab);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightInput = uieditfield(app.InputsTab, 'numeric');
            app.HeightInput.ValueChangedFcn = createCallbackFcn(app, @Inputs, true);
            app.HeightInput.Value = 53.7;
            app.MasskgEditFieldLabel = uilabel(app.InputsTab);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MassInput = uieditfield(app.InputsTab, 'numeric');
            app.MassInput.ValueChangedFcn = createCallbackFcn(app, @Inputs, true);
            app.MassInput.Value = 39.51;
            app.UnitsInput = uiswitch(app.InputsTab, 'slider');
            app.UnitsInput.Items = {'mm.', 'in.'};
            app.UnitsInput.ValueChangedFcn = createCallbackFcn(app, @UnitsInDetect, true);
            app.UnitsInput.Value = 'in.';
            app.OutputSettingsTab = uitab(app.TabGroup);
            app.OutputSettingsTab.Title = 'Output Settings';
            app.IdealDimensionsCheckBox = uicheckbox(app.OutputSettingsTab);
            app.IdealDimensionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputSettings, true);
            app.IdealDimensionsCheckBox.Text = 'Ideal Dimensions';
            app.OriginalDimensionsCheckBox = uicheckbox(app.OutputSettingsTab);
            app.OriginalDimensionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputSettings, true);
            app.OriginalDimensionsCheckBox.Text = 'Original Dimensions';
            app.OriginalDimensionsCheckBox.Value = true;
            app.UnitsSwitch = uiswitch(app.OutputSettingsTab, 'slider');
            app.UnitsSwitch.Items = {'mm', 'in.'};
            app.UnitsSwitch.ValueChangedFcn = createCallbackFcn(app, @OutputSettings, true);
            app.UnitsSwitch.Value = 'mm';
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.HomePlateCheckBox = uicheckbox(app.AdvancedSettingsTab);
            app.HomePlateCheckBox.ValueChangedFcn = createCallbackFcn(app, @Inputs, true);
            app.HomePlateCheckBox.Text = 'Home Plate';
            app.HomePlateCheckBox.Value = true;
            app.PackagingLLabel = uilabel(app.AdvancedSettingsTab);
            app.PackagingLLabel.Text = 'Length (in)';
            app.LengthInput_2 = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.LengthInput_2.Value = 6;
            app.PackWinLabel = uilabel(app.AdvancedSettingsTab);
            app.PackWinLabel.Text = 'Width (in)';
            app.WidthInput_2 = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.WidthInput_2.Value = 7;
            app.PackMkgLabel = uilabel(app.AdvancedSettingsTab);
            app.PackMkgLabel.Text = 'Mass (kg)';
            app.MassInput_2 = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.MassInput_2.Value = 442.253;
            app.PackHinLabel = uilabel(app.AdvancedSettingsTab);
            app.PackHinLabel.Text = 'Height (in)';
            app.HeightInput_2 = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.HeightInput_2.Value = 6.375;
            app.LengthEditFieldLabel_5 = uilabel(app.AdvancedSettingsTab);
            app.LengthEditFieldLabel_5.Text = 'Packaging Spacing';
            app.LengthEditFieldLabel_6 = uilabel(app.AdvancedSettingsTab);
            app.LengthEditFieldLabel_6.Text = 'Trailer Interior';
            app.LengthinLabel = uilabel(app.AdvancedSettingsTab);
            app.LengthinLabel.Text = 'Length (in)';
            app.trailerLength = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.trailerLength.Value = 636;
            app.WidthinLabel = uilabel(app.AdvancedSettingsTab);
            app.WidthinLabel.Text = 'Width (in)';
            app.trailerWidth = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.trailerWidth.Value = 102;
            app.MasskgEditFieldLabel_2 = uilabel(app.AdvancedSettingsTab);
            app.MasskgEditFieldLabel_2.Text = 'Mass (kg)';
            app.trailerMass = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.trailerMass.Value = 21772.434;
            app.WidthinLabel_2 = uilabel(app.AdvancedSettingsTab);
            app.WidthinLabel_2.Text = 'Height (in)';
            app.trailerHeight = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.trailerHeight.Value = 102;
            app.PackMkgLabel_2 = uilabel(app.AdvancedSettingsTab);
            app.PackMkgLabel_2.Text = 'Spacers (in)';
            app.MassInput_4 = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.MassInput_4.Value = 2;
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.ResultsTextArea.ValueChangedFcn = createCallbackFcn(app, @Output, true);
            app.ShippingEfficiencyCalculatorLabel = uilabel(app.UIFigure);
            app.ShippingEfficiencyCalculatorLabel.Text = 'Shipping Efficiency Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Efficiency_Calculator_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end